classdef Concrete_presentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PredictedStrengthEditField     matlab.ui.control.NumericEditField
        PredictedStrengthEditFieldLabel  matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
        MeanAbsoluteErrorEditField     matlab.ui.control.NumericEditField
        MeanAbsoluteErrorEditFieldLabel  matlab.ui.control.Label
        MeanSquareErrorEditField       matlab.ui.control.NumericEditField
        MeanSquareErrorEditFieldLabel  matlab.ui.control.Label
        RSquareEditField               matlab.ui.control.NumericEditField
        RSquareEditFieldLabel          matlab.ui.control.Label
        RegressionButton               matlab.ui.control.Button
        CorrelationButton              matlab.ui.control.Button
        CorrelationHeatMapButton       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CorrelationHeatMapButton
        function CorrelationHeatMapButtonPushed(app, event)
            df=readtable("concrete_data.csv")
            app.UITable.Data=df
           features = df.Properties.VariableNames;

corrMatrix = corr(df{:, features});

figure('Position', [100, 100, 800, 800]);

% Create a heatmap
heatmap = imagesc(corrMatrix);
colorbar; 

textColors = repmat(corrMatrix(:) > 0, 1, 3); 
textStrings = num2str(corrMatrix(:), '%.2f'); 
textStrings = strtrim(cellstr(textStrings));   
[x, y] = meshgrid(1:numel(features));  
hStrings = text(x(:), y(:), textStrings(:), 'HorizontalAlignment', 'center', 'FontSize', 12);

set(hStrings, {'Color'}, num2cell(textColors, 2));

xticks(1:numel(features));
yticks(1:numel(features));

xticklabels(features);
yticklabels(features);

xtickangle(45);

title('Correlation Heatmap');

axis equal tight;
box on;


        end

        % Button pushed function: CorrelationButton
        function CorrelationButtonPushed(app, event)
            df=readtable("concrete_data.csv")
            corrplot(df)
        end

        % Button pushed function: RegressionButton
        function RegressionButtonPushed(app, event)
              df=readtable("concrete_data.csv")
              Y = df.Strength;
X = df(:, ~strcmp(df.Properties.VariableNames, 'Strength'));

rng(7);

testSize = 0.3;

numTest = round(testSize * height(df));

indices = randperm(height(df));

testIndices = indices(1:numTest);
trainIndices = indices(numTest+1:end);

X_train = X(trainIndices, :);
X_test = X(testIndices, :);
Y_train = Y(trainIndices);
Y_test = Y(testIndices);

fprintf('X_train size: %d x %d\n', size(X_train));
fprintf('X_test size: %d x %d\n', size(X_test));
fprintf('Y_train size: %d x %d\n', size(Y_train));
fprintf('Y_test size: %d x %d\n', size(Y_test));
            X_train_matrix = table2array(X_train);
Y_train_vector = Y_train;

mdl = fitrgp(X_train_matrix, Y_train_vector);

X_test_matrix = table2array(X_test);

Y_pred = predict(mdl, X_test_matrix);

SSE = sum((Y_test - Y_pred).^2);
SST = sum((Y_test - mean(Y_test)).^2);
r_squared_GPR = 1 - (SSE / SST);

mae_GPR = mean(abs(Y_pred - Y_test));

mse_GPR = mean((Y_pred - Y_test).^2);
app.RSquareEditField.Value=(r_squared_GPR);
app.MeanAbsoluteErrorEditField.Value=(mae_GPR);
app.MeanSquareErrorEditField.Value=(mse_GPR);

fprintf('R squared_GPR: %.4f\n', r_squared_GPR);
fprintf('MAE_GPR: %.4f\n', mae_GPR);
fprintf('MSE_GPR: %.4f\n', mse_GPR);

        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
  df=readtable("concrete_data.csv");
            Y = df.Strength;
X = df(:, ~strcmp(df.Properties.VariableNames, 'Strength'));

rng(7);

testSize = 0.3;

numTest = round(testSize * height(df));

indices = randperm(height(df));

testIndices = indices(1:numTest);
trainIndices = indices(numTest+1:end);

X_train = X(trainIndices, :);
X_test = X(testIndices, :);
Y_train = Y(trainIndices);
Y_test = Y(testIndices);

%fprintf('X_train size: %d x %d\n', size(X_train));
%fprintf('X_test size: %d x %d\n', size(X_test));
%fprintf('Y_train size: %d x %d\n', size(Y_train));
%fprintf('Y_test size: %d x %d\n', size(Y_test));

X_train_matrix = table2array(X_train);
Y_train_vector = Y_train;

mdl = fitrgp(X_train_matrix, Y_train_vector);

X_test_matrix = table2array(X_test);

Y_pred = predict(mdl, X_test_matrix);

SSE = sum((Y_test - Y_pred).^2);
SST = sum((Y_test - mean(Y_test)).^2);
r_squared_GPR = 1 - (SSE / SST);

mae_GPR = mean(abs(Y_pred - Y_test));

mse_GPR = mean((Y_pred - Y_test).^2);


            input_cement = input('Enter Cement value: ');
input_blast_furnace_slag = input('Enter Blast Furnace Slag value: ');
input_fly_ash = input('Enter Fly Ash value: ');
input_water = input('Enter Water value: ');
input_superplasticizer = input('Enter Superplasticizer value: ');
input_coarse_aggregate = input('Enter Coarse Aggregate value: ');
input_fine_aggregate = input('Enter Fine Aggregate value: ');
input_age = input('Enter Age value: ');

user_input = table(input_cement, input_blast_furnace_slag, input_fly_ash, input_water, ...
                   input_superplasticizer, input_coarse_aggregate, input_fine_aggregate, input_age);

user_input_matrix = table2array(user_input);
predicted_strength = predict( mdl,user_input_matrix);
app.PredictedStrengthEditField.Value=predicted_strength
fprintf('Predicted Strength: %.4f\n', predicted_strength);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column  9'};
            app.UITable.RowName = {};
            app.CorrelationHeatMapButton = uibutton(app.UIFigure, 'push');
            app.CorrelationHeatMapButton.ButtonPushedFcn = createCallbackFcn(app, @CorrelationHeatMapButtonPushed, true);
            app.CorrelationHeatMapButton.Text = 'Correlation Heat Map';
            app.CorrelationButton = uibutton(app.UIFigure, 'push');
            app.CorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @CorrelationButtonPushed, true);
            app.CorrelationButton.Text = 'Correlation';
            app.RegressionButton = uibutton(app.UIFigure, 'push');
            app.RegressionButton.ButtonPushedFcn = createCallbackFcn(app, @RegressionButtonPushed, true);
            app.RegressionButton.Text = 'Regression';
            app.RSquareEditFieldLabel = uilabel(app.UIFigure);
            app.RSquareEditFieldLabel.Text = 'R Square';
            app.RSquareEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanSquareErrorEditFieldLabel = uilabel(app.UIFigure);
            app.MeanSquareErrorEditFieldLabel.Text = 'Mean Square Error';
            app.MeanSquareErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanAbsoluteErrorEditFieldLabel = uilabel(app.UIFigure);
            app.MeanAbsoluteErrorEditFieldLabel.Text = 'Mean Absolute Error';
            app.MeanAbsoluteErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.PredictedStrengthEditFieldLabel = uilabel(app.UIFigure);
            app.PredictedStrengthEditFieldLabel.Text = 'Predicted Strength';
            app.PredictedStrengthEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Concrete_presentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end